classdef if4073_tugas2_13519135_13519141 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ddNoiseType             matlab.ui.control.DropDown
        FilterLabel_4           matlab.ui.control.Label
        labelSigma              matlab.ui.control.Label
        sliderSigma             matlab.ui.control.Slider
        sigmavalueLabel         matlab.ui.control.Label
        buttonAddNoise          matlab.ui.control.Button
        ddFreqMethod            matlab.ui.control.DropDown
        FilterLabel_3           matlab.ui.control.Label
        ddKernel                matlab.ui.control.DropDown
        FilterLabel_2           matlab.ui.control.Label
        labelFiltBlue           matlab.ui.control.Label
        labelFiltGreen          matlab.ui.control.Label
        labelFiltGrayscale      matlab.ui.control.Label
        imgFilt                 matlab.ui.control.Image
        labelFiltImage          matlab.ui.control.Label
        buttonProcess           matlab.ui.control.Button
        buttonPickBase          matlab.ui.control.Button
        creatorLabel            matlab.ui.control.Label
        labelProcBlue           matlab.ui.control.Label
        labelProcGreen          matlab.ui.control.Label
        labelProcGrayscale      matlab.ui.control.Label
        imgProc                 matlab.ui.control.Image
        labelProcImage          matlab.ui.control.Label
        labelBaseBlue           matlab.ui.control.Label
        labelBaseGreen          matlab.ui.control.Label
        labelBaseGrayscale      matlab.ui.control.Label
        imgBase                 matlab.ui.control.Image
        labelBaseImage          matlab.ui.control.Label
        sliderKSize             matlab.ui.control.Slider
        kernelsizeLabel         matlab.ui.control.Label
        labelKSize              matlab.ui.control.Label
        labelN                  matlab.ui.control.Label
        labelD0                 matlab.ui.control.Label
        sliderN                 matlab.ui.control.Slider
        nordervalueSliderLabel  matlab.ui.control.Label
        sliderD0                matlab.ui.control.Slider
        d0valueSliderLabel      matlab.ui.control.Label
        ddFuncs                 matlab.ui.control.DropDown
        FilterLabel             matlab.ui.control.Label
        axesFiltBlue            matlab.ui.control.UIAxes
        axesFiltGreen           matlab.ui.control.UIAxes
        axesFiltGrayscale       matlab.ui.control.UIAxes
        axesProcBlue            matlab.ui.control.UIAxes
        axesProcGreen           matlab.ui.control.UIAxes
        axesProcGrayscale       matlab.ui.control.UIAxes
        axesBaseBlue            matlab.ui.control.UIAxes
        axesBaseGreen           matlab.ui.control.UIAxes
        axesBaseGrayscale       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        varBaseImage uint8 % Base Image Variable
        varProcImage uint8 % Processed Image Variable
        varFiltImage uint8 % Filtered Image Variable
        
        valN double = 2; % N Value
        valD0 double = 50; % D0 Value
        valKSize double = 3; % Kernel Size Value
        valSigma double = 1.6; % Sigma Value
    end
    
    methods (Access = private)
        % Source Code berikut diambil dari Tugas 1 13519135-13519029
        %{
          Description: implementation of rgb values to grayscale values
          Output: 2D matrix of size row(matrix), col(matrix), with grayscale
            values using the formula 0.2989 * Red + 0.5870 * Green + 0.1140 * Blue
          Input: 2D Matrix of n dimension n > 0
        %}
        function grayscale = rgb_to_grayscale(~, matrix)
            [~, ~, dim] = size(matrix);
            % if image is already in grayscale values
            if dim == 1, grayscale = matrix; return, end
        
            grayscale = matrix(:,:,1) * 0.2989 + matrix(:,:,2) * 0.587 + matrix(:,:,3) * 0.114;
            grayscale = uint8(grayscale);
        end

        %{
          Description: Calculates occurences of each pixel value for a given matrix
          Output: Vector with 256 columns with values [0,255] of occurences
          Input: 2D pixel matrix
        %}
        function occ = calc_occurences(~, matrix)
            occ = zeros(1,256);
            [row, col] = size(matrix);
            for r = 1:row
                for c = 1:col
                occ(matrix(r,c)+1) = occ(matrix(r,c)+1) + 1;
                end
            end
        end
        
        %{
          Description: Plots occurences of pixel using stem function
          Output: Stem of pixel occurences
          Input: Vector with 256 columns of pixel occurences,
            target plot destination
        %}
        function occurences_to_plot(~, occ, target)
            target.Visible = 'on';
            occ(occ==0) = nan; % converts 0 to nan, to not show in stem plot
            stem(target, 0:255, occ, 'MarkerSize', 3);
            xlim(target, [0 255]); % limit x value to in range of [0, 255]
        end
        % Akhir Source Code Tugas 1

        %{
          Description: Plots stem of image rgb and/or grayscale values, with option to show images
          Output: Figures of plots and images
          Input: image matrix (grayscale/colored), targets of plot
            destinations
        %}
        function img_plot(app, img, targets, labels)
            [~, ~, dim] = size(img);
            labels(4).Visible = 'on';

            % if image is in grayscale
            if dim == 1
                targets(1).Visible = 'on';
                labels(1).Visible = 'on';
                labels(1).Text = 'Grayscale Histogram';
                app.occurences_to_plot(app.calc_occurences(img), targets(1));
                return;
            end
        
            [r, g, b] = deal(img(:,:,1), img(:,:,2), img(:,:,3));
            for target = targets, target.Visible = 'on'; end
            for label = labels, label.Visible = 'on'; end
            labels(1).Text = 'Red Histogram';

            app.occurences_to_plot(app.calc_occurences(r), targets(1));
            app.occurences_to_plot(app.calc_occurences(g), targets(2));
            app.occurences_to_plot(app.calc_occurences(b), targets(3));
        end

        % Spek Functions
        %{
          Description: Generate gaussian kernel for spatial blurring using gaussian filter
          Output: Kernel of size m x m with input sigma using the formula
            1/(2πσ²) * exp(-(x² + y² / 2σ²))
            *modified to make the center point the highest value
          Input: size of kernel (m), sigma input
        %}
        function kernel = gauss_kernel(~, m, sigma)
            kernel = zeros(m, m); % init kernel with zeros of size m x m
        
            c = double(idivide(m, int8(2))) + 1; % get the center point of the kernel matrix
        
            % apply modified formula
            for i=1:m
                for j=1:m
                    kernel(i, j) = 1 / (2 * pi * sigma) * exp(-((i-c) ^ 2 + (j-c) ^ 2) / (2 * sigma ^ 2));
                end
            end
        
            kernel = kernel / sum(kernel(:)); % normalize kernel
        end

        %{
          Description: Function to perform convolution on matrix with mask/kernel
          Output: Convoluted image using mask/kernel
          Input: Matrix of image, Convolution Mask/Kernel, option to add the convoluted image with base image 
        %}
        function convoluted = convolution(~, mat, mask, add)
            convoluted = mat;
            original = mat;
            mask = double(mask);
            [m_row, m_col, ~] = size(mask);
        
            % guard for if mask size is even or not symmetric
            if m_row ~= m_col || ~(mod(m_row, 2)), return; end
        
            % center of kernel/mask
            mc = double(idivide(m_row, int8(2)));
        
            % pad array with 0s (Edge Handling: Constant)
            mat = padarray(mat, [mc mc], 0, 'both');
            [row, col, dim] = size(mat); % size of matrix after padding with 0s
        
            % if sum of all elements in mask is > 1, divide each el by sum
            mask_sum = sum(mask(:));
            if mask_sum > 1, mask = mask / mask_sum; end
        
            for d=1:dim
                for i=mc+1:row-mc
                    for j=mc+1:col-mc
                        sliced = double(mat(i-mc:i+mc, j-mc:j+mc, d));
                        convoluted(i-mc, j-mc, d) = uint8(dot(sliced(:), mask(:)));
                    end
                end
            end

            if add, convoluted = original + convoluted; end
        end

        %{
          Description: Blur image using convolution method, with two filter options ('mean', and 'gaussian')
          Output: Blurred image through convolution
          Input: Matrix image, size of kernel/mask, sigma for generating gaussian kernel,
            and filter option ('mean', or 'gaussian')
        %}
        % opt == 'gaussian', opt == 'mean' filters
        function blurred = conv_blur(app, mat, m_size, filt_opt, sigma)
            blurred = mat;
            if ~mod(m_size, 2), m_size = abs(m_size) + 1; end % if even-sized kernel, add 1 to make it odd
        
            mask = zeros(m_size, m_size); % init mask to be used for convolution
        
            if strcmp(filt_opt, 'gaussian')
                if isnan(sigma) || ~sigma, sigma = 1.6; end
                mask = app.gauss_kernel(m_size, sigma); % generate gaussian kernel using sigma of 1.6
            elseif strcmp(filt_opt, 'mean')
                mask = mask + 1; % add 1 to make it mean filter
            else
                return;
            end
        
            blurred = app.convolution(mat, mask, false);
        end

        %{
          Description: Blur image in frequency realm (lowpass filter)
          Output: Blurred image with specified LowPass Filter method and specified
            input parameters
          Input: Image matrix, blur options (ilpf || glpf || blpf), cutoff value,
            n value for Butterworth method
        %}
        function blurred = freq_blur(~, mat, blur_opt, cutoff, n)
            blurred = mat; % init return value same as input
            [row, col, dim] = size(mat);
        
            [p, q] = deal(row * 2, col * 2); % assigning p and q for padding purposes
            mat = padarray(im2double(mat), [row col], 0, 'post'); % padding matrix
        
            f = fftn(mat); % fast n-dimension fourier transform of image matrix input
            
            [u, v] = deal(0:(p-1), 0:(q-1)); % assign range of variables
            [idx, idy] = deal(find(u > p/2), find(v > q/2)); % get indices to use in meshgrid
            
            u(idx) = u(idx) - p;
            v(idy) = v(idy) - q;
        
            % get meshgrid arrays
            [mv, mu] = meshgrid(v, u);
            d = sqrt(mu .^ 2 + mv .^ 2);
        
            if isnan(cutoff) || ~cutoff, cutoff = 50; end % set default cutoff value if nan or 0
            if strcmp(blur_opt, 'ilpf') % ilpf (Ideal LowPass Filter)
                H = double(d <= abs(cutoff));
            elseif strcmp(blur_opt, 'glpf') % glpf (Gaussian LowPass Filter)
                H = exp(-(d .^ 2) ./ (2 * (abs(cutoff) ^ 2)));
            elseif strcmp(blur_opt, 'blpf') % blpf (Butterworth LowPass Filter)
                if isnan(n) || ~n, n = 1; end
                H = 1 ./ (1 + (d ./ abs(cutoff)) .^ (2 * abs(n)));
            else, return; % if LPF not specified, return input image
            end
        
            lpf = H .* f; % multiply f with H (element-wise)
            lpf = real(ifftn(lpf)); % inverse n-dimension fourier transform (from freq to spatial)
            % convert image (double) to image (uint8)
            blurred = im2uint8(lpf(1:row, 1:col, 1:dim)); % cut off the padding to get blurred image
        end

        %{
          Description: Sharpen image in frequency realm (highpass filter)
          Output: Sharpened image with specified HighPass Filter method and specified
            input parameters
          Input: Image matrix, sharpen options (ihpf || ghpf || bhpf), cutoff value,
            n value for Butterworth method
        %}
        function [sharpened, filtered] = freq_sharpen(~, mat, shrp_opt, cutoff, n)
            sharpened = mat; % init return value same as input
            original = mat; % original matrix values
            [row, col, dim] = size(mat);
        
            [p, q] = deal(row * 2, col * 2); % assigning p and q for padding purposes
            mat = padarray(im2double(mat), [row col], 0, 'post'); % padding matrix
        
            f = fftn(mat); % fast n-dimension fourier transform of image matrix input
            
            [u, v] = deal(0:(p-1), 0:(q-1)); % assign range of variables
            [idx, idy] = deal(find(u > p/2), find(v > q/2)); % get indices to use in meshgrid
            
            u(idx) = u(idx) - p;
            v(idy) = v(idy) - q;
        
            % get meshgrid arrays
            [mv, mu] = meshgrid(v, u);
            d = sqrt(mu .^ 2 + mv .^ 2);
            
            if isnan(cutoff) || ~cutoff, cutoff = p / 20; end % default cutoff variable
            if strcmp(shrp_opt, 'ihpf') % ihpf (Ideal HighPass Filter)
                H = double(d <= cutoff);
            elseif strcmp(shrp_opt, 'ghpf') % ghpf (Gaussian HighPass Filter)
                H = exp(-(d .^ 2) ./ (2 * (cutoff ^ 2)));
            elseif strcmp(shrp_opt, 'bhpf') % bhpf (Butterworth HighPass Filter)
                if isnan(n) || ~n, n = 1; end
                H = 1 ./ (1 + (d ./ cutoff) .^ (2 * abs(n)));
            else, return; % if LPF not specified, return input image
            end
        
            H = 1 - H; % high pass is 1 - low pass
            hpf = H .* f; % multiply f with 1-H (element-wise), 1-H to sharpen image
            hpf = real(ifftn(hpf)); % inverse n-dimension fourier transform (from freq to spatial)
            filtered = im2uint8(hpf(1:row, 1:col, 1:dim)); % cut off the padding to get sharpened image
            sharpened = original + filtered; % sharpened image is original + filtered
        end
        
        %{
          Description: Function to add noise to image (salt and pepper)
          Output: Image with salt and pepper noise added
          Input: Image matrix
        %}
        function noise_added = noise_add_sp(~, mat)
            noise_added = imnoise(mat, 'salt & pepper', 0.05);
        end

        %{
          Description: Function to remove noise using median filter
          Output: Noise-removed image using the median filter
          Input: Image matrix, kernel size of median filter
        %}
        function noise_free = noise_removal_median(~, mat, k_size)
            noise_free = mat;
        
            % guard if k_size is even, then add 1 to make it odd
            if ~(mod(k_size, 2)), k_size = abs(k_size) + 1; end
        
            % center of kernel size
            mc = double(idivide(k_size, int8(2)));
        
            % pad array with NaNs (Edge Handling: Constant), remove nan when finding median
            mat = padarray(mat, [double(mc) double(mc)], nan, 'both');
            [row, col, dim] = size(mat); % size of matrix after padding with NaNs
        
            for d=1:dim
                for i=mc+1:row-mc
                    for j=mc+1:col-mc
                        sliced = mat(i-mc:i+mc, j-mc:j+mc, d); % slice image with size of kernel
                        sliced = sliced(:); % flatten array
                        sliced = sliced(~isnan(sliced)); % remove nan
                        noise_free(i-mc, j-mc, d) = uint8(median(sliced)); % replace with median of sliced
                    end
                end
            end
        end

        %{
          Description: Function to brighten image by frequency domain altering using high boost filter concept
          Output: brightened image
          Input: original image
        %}
        function brightened = freq_brightening(app, img, intensity)
            [rows, cols, rgb] = size(img);
        
            blurimg = app.freq_blur(img, 'blpf', 50, 1);
            imgpad = padarray(im2double(img), [rows cols], 0, 'post'); % padding matrix
            imgf = fftn(imgpad);

            blurimgpad = padarray(im2double(blurimg), [rows cols], 0, 'post'); % padding matrix
            blurimgf = fftn(blurimgpad);
        
            % high boost filter
            if isnan(intensity) || ~intensity, intensity = 2.8; end
            newimgf = (intensity.*imgf) - blurimgf;
            newimg = real(ifftn(newimgf)); % inverse n-dimension fourier transform (from freq to spatial)
            brightened = im2uint8(newimg(1:rows, 1:cols, 1:rgb)); % cut off the padding to get blurred image
        end

        %{
          Description: Function to reduce noice
          Output: fixed image
          Input: image with noise
        %}
        
        function [newimg,img] = noise_reduction(app, img,type)
            [~, ~, rgb] = size(img);
        
            if (rgb>1)
                grayscale = app.rgb_to_grayscale(img);
                img = grayscale;
            end
        
            imgf = fftshift(fftn(im2double(img)));
            % figure; imagesc(log1p(abs(imgf))), colormap gray;
        
            if strcmp(type,'lena1')
                indexes = [
                    22 123 26 127;
                    209 103 212 107;
                    124 123 128 127;
                    104 103 108 107;
                    1 105 110 105;
                    120 105 231 105;
                    1 125 103 125;
                    120 125 231 125;
                    106 205 106 212;
                    106 2 106 4;
                    126 226 126 228;
                    126 18 126 25;
                ];
        
                for i=1:size(indexes,1)
                    range = indexes(i,:);
                    imgf(range(1):range(3),range(2):range(4)) = 0;
                end
            elseif strcmp(type,'science')
                indexes = [
                    1 320 345 324;
                    1 440 345 460;
                    540 320 881 324;
                    540 440 881 460;
                    441 1 441 320;
                    441 579 441 881;
                    1 577 345 579;
                    540 577 881 579;
                    335 126 350 126;
                    335 191 350 192;
                    335 256 350 258;
                    335 780 350 780;
                    335 697 350 697;
                    335 713 350 715;
                    335 843 350 846;
                    335 649 350 650;
                ];
        
                for i=1:size(indexes,1)
                    range = indexes(i,:);
                    imgf(range(1):range(3),range(2):range(4)) = 0;
                end
            elseif strcmp(type,'lena2')
                indexes = [
                    1 122 106 122;
                    148 122 247 122;
                    155 1 157 102;
                    171 1 173 83;
                    185 1 189 60;
                    204 1 204 43;
                    219 27 222 27;
                    91 144 93 243;
                    75 165 77 243;
                    60 185 60 243;
                    44 201 44 243;
                    108 136 108 140;
                    1 106 247 106;
                    1 138 247 138;
                    1 169 247 170;
                    1 184 247 186;
                    1 201 247 201;
                    1 217 247 217;
                    1 27 247 27;
                    1 43 247 43;
                    1 58 247 60;
                    1 74 247 76;
                    245 1 247 3;
                    139 105 141 107;
                    123 1 125 58;
                    123 186 125 243;
                    58 183 64 187;
                ];
        
                for i=1:size(indexes,1)
                    range = indexes(i,:);
                    imgf(range(1):range(3),range(2):range(4)) = 0;
                end
        
                i = 247;
                j = 1;
                while (i>3 && j<240)
                    if ((i<100 || i>150) && (j<100 || j>150))
                        imgf(i-3:i,j:j+3) = 0;            
                    end
                    i = i-3;
                    j = j+3;
                end
            elseif strcmp(type,'building')
                indexes = [
                    1 159 100 161;
                    140 159 237 161;
                    1 258 237 262;
                    1 58 237 62;
                ];
        
                for i=1:size(indexes,1)
                    range = indexes(i,:);
                    imgf(range(1):range(3),range(2):range(4)) = 0;
                end
            end
            
            newimg = im2uint8(real(ifftn(ifftshift(imgf)))); % inverse n-dimension fourier transform (from freq to spatial)

        end

        % Spek Functions end
        
        function results = baseTargets(app)
            results = [app.axesBaseGrayscale, app.axesBaseGreen, app.axesBaseBlue];
        end

        function results = baseLabels(app)
            results = [app.labelBaseGrayscale, app.labelBaseGreen, app.labelBaseBlue, app.labelBaseImage];
        end

        function results = procTargets(app)
            results = [app.axesProcGrayscale, app.axesProcGreen, app.axesProcBlue];
        end

        function results = procLabels(app)
            results = [app.labelProcGrayscale, app.labelProcGreen, app.labelProcBlue, app.labelProcImage];
        end

        function results = filtTargets(app)
            results = [app.axesFiltGrayscale, app.axesFiltGreen, app.axesFiltBlue];
        end

        function results = filtLabels(app)
            results = [app.labelFiltGrayscale, app.labelFiltGreen, app.labelFiltBlue, app.labelFiltImage];
        end

        function value = round_odd(~, value)
            idx = mod(value, 2) < 1;
            value = floor(value);
            value(idx) = value(idx) + 1;
        end

        function resetBase(app)
            for lbl = app.baseLabels(), lbl.Visible = 'off'; end
            for target = app.baseTargets()
                target.Visible = 'off';
                cla(target);
            end
            app.imgBase.Visible = 'off';
        end

        function resetProc(app)
            for lbl = app.procLabels(), lbl.Visible = 'off'; end
            for target = app.procTargets()
                target.Visible = 'off';
                cla(target);
            end
            app.imgProc.Visible = 'off';
        end

        function resetFilt(app)
            for lbl = app.filtLabels(), lbl.Visible = 'off'; end
            for target = app.filtTargets()
                target.Visible = 'off';
                cla(target);
            end
            app.imgFilt.Visible = 'off';
            app.axesFiltGrayscale.YDir = 'normal';
            app.axesFiltGreen.YDir = 'normal';
        end

        function showImageProc(app)
            app.resetProc();
            [~, ~, dim] = size(app.varProcImage);
            app.imgProc.Visible = 'on';

            if dim == 1, app.imgProc.ImageSource = repmat(app.varProcImage, 1, 1, 3);
            else, app.imgProc.ImageSource = app.varProcImage;
            end

            app.img_plot(app.varProcImage, app.procTargets(), app.procLabels());
        end

        function showImageFilt(app,type)
            app.resetFilt();
            if type == 0
                [~, ~, dim] = size(app.varFiltImage);
                app.imgFilt.Visible = 'on';
    
                if dim == 1, app.imgFilt.ImageSource = repmat(app.varFiltImage, 1, 1, 3);
                else, app.imgFilt.ImageSource = app.varFiltImage;
                end
                app.img_plot(app.varFiltImage, app.filtTargets(), app.filtLabels());
            elseif type == 1
                img1 = fftshift(fftn(app.varFiltImage));
                img2 = fftshift(fftn(app.varProcImage));
                imagesc(log1p(abs(img1)), 'parent', app.axesFiltGrayscale), colormap(app.axesFiltGrayscale, 'gray');
                imagesc(log1p(abs(img2)), 'parent', app.axesFiltGreen), colormap(app.axesFiltGreen, 'gray');
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: sliderKSize
        function sliderKSizeValueChanged(app, event)
            value = app.sliderKSize.Value;
            app.valKSize = app.round_odd(value);
            app.labelKSize.Text = string(app.valKSize);
            app.sliderKSize.Value = app.valKSize;
        end

        % Value changed function: sliderD0
        function sliderD0ValueChanged(app, event)
            value = app.sliderD0.Value;
            app.valD0 = round(value);
            app.labelD0.Text = string(app.valD0);
            app.sliderD0.Value = app.valD0;
        end

        % Value changed function: sliderN
        function sliderNValueChanged(app, event)
            value = app.sliderN.Value;
            app.valN = round(value);
            app.labelN.Text = string(app.valN);
            app.sliderN.Value = app.valN;
        end

        % Button pushed function: buttonProcess
        function buttonProcessButtonPushed(app, event)
            if isempty(app.varBaseImage), return; end % return if base image is not loaded
            app.resetFilt();
            app.resetProc();
            app.buttonProcess.Text = 'Processing...';
            switch app.ddFuncs.Value
                case 'Convolutional Blurring'
                    app.varProcImage = app.conv_blur(app.varBaseImage, app.valKSize, lower(app.ddKernel.Value), app.valSigma);
                case 'Frequency Blurring (LPF)'
                    switch app.ddFreqMethod.Value
                        case 'Ideal', lpf = 'ilpf';
                        case 'Butterworth', lpf = 'blpf';
                        case 'Gaussian', lpf = 'glpf';
                        otherwise, lpf = 'glpf';
                    end
                    app.varProcImage = app.freq_blur(app.varBaseImage, lpf, app.valD0, app.valN);
                case 'Frequency Sharpening (HPF)'
                    switch app.ddFreqMethod.Value
                        case 'Ideal', hpf = 'ihpf';
                        case 'Butterworth', hpf = 'bhpf';
                        case 'Gaussian', hpf = 'ghpf';
                        otherwise, hpf = 'ghpf';
                    end
                    [app.varProcImage, app.varFiltImage] = app.freq_sharpen(app.varBaseImage, hpf, app.valD0, app.valN);
                    app.showImageFilt(0);
                case 'Frequency Brightening'
                    app.varProcImage = app.freq_brightening(app.varBaseImage, app.valSigma);
                case 'SaltPepper Noise Removal (Median Filter)'
                    app.varProcImage = app.noise_removal_median(app.varBaseImage, app.valKSize);
                case 'Periodic Noise Removal'
                    [app.varProcImage,app.varFiltImage] = app.noise_reduction(app.varBaseImage, lower(app.ddNoiseType.Value));
                    app.showImageFilt(1);
                otherwise
                    app.buttonProcess.Text = 'Process';
                    return
            end

            app.buttonProcess.Text = 'Process';
            app.showImageProc();
        end

        % Button pushed function: buttonPickBase
        function buttonPickBaseButtonPushed(app, event)
            [base_file_name, base_file_path] = uigetfile({'*.tif;*.tiff;*.png;*.jpeg;*.jpg;*.bmp'}, 'Image Selector');
            if ~base_file_name, return, end
            app.resetBase(); app.resetProc(); app.resetFilt(); % reset showed images
            app.varBaseImage = imread(strcat(base_file_path, base_file_name));
            [~, ~, dim] = size(app.varBaseImage);
            app.labelBaseImage.Visible = 'on';
            app.imgBase.Visible = 'on';

            if dim == 1, app.imgBase.ImageSource = repmat(app.varBaseImage, 1, 1, 3);
            elseif all([app.varBaseImage(:,:,1) == app.varBaseImage(:,:,2), app.varBaseImage(:,:,2) == app.varBaseImage(:,:,3)])
                app.varBaseImage = app.varBaseImage(:,:,1);
                app.imgBase.ImageSource = repmat(app.varBaseImage, 1, 1, 3);
            else, app.imgBase.ImageSource = app.varBaseImage;
            end

            app.img_plot(app.varBaseImage, app.baseTargets(), app.baseLabels());
        end

        % Button pushed function: buttonAddNoise
        function buttonAddNoiseButtonPushed(app, event)
            if isempty(app.varBaseImage), return; end

            [~, ~, dim] = size(app.varBaseImage);
            app.varBaseImage = app.noise_add_sp(app.varBaseImage);

            app.labelBaseImage.Visible = 'on';
            app.imgBase.Visible = 'on';

            if dim == 1, app.imgBase.ImageSource = repmat(app.varBaseImage, 1, 1, 3);
            else, app.imgBase.ImageSource = app.varBaseImage;
            end

            app.img_plot(app.varBaseImage, app.baseTargets(), app.baseLabels());
        end

        % Value changed function: sliderSigma
        function sliderSigmaValueChanged(app, event)
            value = app.sliderSigma.Value;
            app.valSigma = round(value, 1);
            app.sliderSigma.Value = app.valSigma;
            app.labelSigma.Text = string(app.valSigma);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axesBaseGrayscale = uiaxes(app.UIFigure);
            title(app.axesBaseGrayscale, 'Title')
            xlabel(app.axesBaseGrayscale, 'X')
            ylabel(app.axesBaseGrayscale, 'Y')
            zlabel(app.axesBaseGrayscale, 'Z')
            app.axesBaseGrayscale.Visible = 'off';
            app.axesBaseGreen = uiaxes(app.UIFigure);
            title(app.axesBaseGreen, 'Title')
            xlabel(app.axesBaseGreen, 'X')
            ylabel(app.axesBaseGreen, 'Y')
            zlabel(app.axesBaseGreen, 'Z')
            app.axesBaseGreen.Visible = 'off';
            app.axesBaseBlue = uiaxes(app.UIFigure);
            title(app.axesBaseBlue, 'Title')
            xlabel(app.axesBaseBlue, 'X')
            ylabel(app.axesBaseBlue, 'Y')
            zlabel(app.axesBaseBlue, 'Z')
            app.axesBaseBlue.Visible = 'off';
            app.axesProcGrayscale = uiaxes(app.UIFigure);
            title(app.axesProcGrayscale, 'Title')
            xlabel(app.axesProcGrayscale, 'X')
            ylabel(app.axesProcGrayscale, 'Y')
            zlabel(app.axesProcGrayscale, 'Z')
            app.axesProcGrayscale.Visible = 'off';
            app.axesProcGreen = uiaxes(app.UIFigure);
            title(app.axesProcGreen, 'Title')
            xlabel(app.axesProcGreen, 'X')
            ylabel(app.axesProcGreen, 'Y')
            zlabel(app.axesProcGreen, 'Z')
            app.axesProcGreen.Visible = 'off';
            app.axesProcBlue = uiaxes(app.UIFigure);
            title(app.axesProcBlue, 'Title')
            xlabel(app.axesProcBlue, 'X')
            ylabel(app.axesProcBlue, 'Y')
            zlabel(app.axesProcBlue, 'Z')
            app.axesProcBlue.Visible = 'off';
            app.axesFiltGrayscale = uiaxes(app.UIFigure);
            title(app.axesFiltGrayscale, 'Title')
            xlabel(app.axesFiltGrayscale, 'X')
            ylabel(app.axesFiltGrayscale, 'Y')
            zlabel(app.axesFiltGrayscale, 'Z')
            app.axesFiltGrayscale.Visible = 'off';
            app.axesFiltGreen = uiaxes(app.UIFigure);
            title(app.axesFiltGreen, 'Title')
            xlabel(app.axesFiltGreen, 'X')
            ylabel(app.axesFiltGreen, 'Y')
            zlabel(app.axesFiltGreen, 'Z')
            app.axesFiltGreen.Visible = 'off';
            app.axesFiltBlue = uiaxes(app.UIFigure);
            title(app.axesFiltBlue, 'Title')
            xlabel(app.axesFiltBlue, 'X')
            ylabel(app.axesFiltBlue, 'Y')
            zlabel(app.axesFiltBlue, 'Z')
            app.axesFiltBlue.Visible = 'off';
            app.FilterLabel = uilabel(app.UIFigure);
            app.FilterLabel.Text = 'Filter';
            app.ddFuncs = uidropdown(app.UIFigure);
            app.ddFuncs.Items = {'Convolutional Blurring', 'Frequency Blurring (LPF)', 'Frequency Sharpening (HPF)', 'Frequency Brightening', 'SaltPepper Noise Removal (Median Filter)', 'Periodic Noise Removal'};
            app.ddFuncs.Value = 'Convolutional Blurring';
            app.d0valueSliderLabel = uilabel(app.UIFigure);
            app.d0valueSliderLabel.Text = {'d0'; 'value'};
            app.sliderD0 = uislider(app.UIFigure);
            app.sliderD0.Limits = [10 110];
            app.sliderD0.ValueChangedFcn = createCallbackFcn(app, @sliderD0ValueChanged, true);
            app.sliderD0.Value = 50;
            app.nordervalueSliderLabel = uilabel(app.UIFigure);
            app.nordervalueSliderLabel.Text = {'n order'; 'value'};
            app.sliderN = uislider(app.UIFigure);
            app.sliderN.Limits = [1 11];
            app.sliderN.ValueChangedFcn = createCallbackFcn(app, @sliderNValueChanged, true);
            app.sliderN.Value = 2;
            app.labelD0 = uilabel(app.UIFigure);
            app.labelD0.Text = '50';
            app.labelN = uilabel(app.UIFigure);
            app.labelN.Text = '2';
            app.labelKSize = uilabel(app.UIFigure);
            app.labelKSize.Text = '3';
            app.kernelsizeLabel = uilabel(app.UIFigure);
            app.kernelsizeLabel.Text = {'kernel'; 'size'};
            app.sliderKSize = uislider(app.UIFigure);
            app.sliderKSize.Limits = [3 23];
            app.sliderKSize.ValueChangedFcn = createCallbackFcn(app, @sliderKSizeValueChanged, true);
            app.sliderKSize.Value = 3;
            app.labelBaseImage = uilabel(app.UIFigure);
            app.labelBaseImage.Visible = 'off';
            app.labelBaseImage.Text = 'Base Image';
            app.imgBase = uiimage(app.UIFigure);
            app.imgBase.Visible = 'off';
            app.labelBaseGrayscale = uilabel(app.UIFigure);
            app.labelBaseGrayscale.Visible = 'off';
            app.labelBaseGrayscale.Text = 'Grayscale Histogram';
            app.labelBaseGreen = uilabel(app.UIFigure);
            app.labelBaseGreen.Visible = 'off';
            app.labelBaseGreen.Text = 'Green Histogram';
            app.labelBaseBlue = uilabel(app.UIFigure);
            app.labelBaseBlue.Visible = 'off';
            app.labelBaseBlue.Text = 'Blue Histogram';
            app.labelProcImage = uilabel(app.UIFigure);
            app.labelProcImage.Visible = 'off';
            app.labelProcImage.Text = 'Processed Image';
            app.imgProc = uiimage(app.UIFigure);
            app.imgProc.Visible = 'off';
            app.labelProcGrayscale = uilabel(app.UIFigure);
            app.labelProcGrayscale.Visible = 'off';
            app.labelProcGrayscale.Text = 'Grayscale Histogram';
            app.labelProcGreen = uilabel(app.UIFigure);
            app.labelProcGreen.Visible = 'off';
            app.labelProcGreen.Text = 'Green Histogram';
            app.labelProcBlue = uilabel(app.UIFigure);
            app.labelProcBlue.Visible = 'off';
            app.labelProcBlue.Text = 'Blue Histogram';
            app.creatorLabel = uilabel(app.UIFigure);
            app.creatorLabel.Text = 'by 13519135 - Naufal Alexander Suryasumirat & 13519141- Naufal Yahya Kurnianto';
            app.buttonPickBase = uibutton(app.UIFigure, 'push');
            app.buttonPickBase.ButtonPushedFcn = createCallbackFcn(app, @buttonPickBaseButtonPushed, true);
            app.buttonPickBase.Text = 'Pick Base Image';
            app.buttonProcess = uibutton(app.UIFigure, 'push');
            app.buttonProcess.ButtonPushedFcn = createCallbackFcn(app, @buttonProcessButtonPushed, true);
            app.buttonProcess.Text = 'Process';
            app.labelFiltImage = uilabel(app.UIFigure);
            app.labelFiltImage.Visible = 'off';
            app.labelFiltImage.Text = 'Filtered Image';
            app.imgFilt = uiimage(app.UIFigure);
            app.imgFilt.Visible = 'off';
            app.labelFiltGrayscale = uilabel(app.UIFigure);
            app.labelFiltGrayscale.Visible = 'off';
            app.labelFiltGrayscale.Text = 'Grayscale Histogram';
            app.labelFiltGreen = uilabel(app.UIFigure);
            app.labelFiltGreen.Visible = 'off';
            app.labelFiltGreen.Text = 'Green Histogram';
            app.labelFiltBlue = uilabel(app.UIFigure);
            app.labelFiltBlue.Visible = 'off';
            app.labelFiltBlue.Text = 'Blue Histogram';
            app.FilterLabel_2 = uilabel(app.UIFigure);
            app.FilterLabel_2.Text = 'Kernel';
            app.ddKernel = uidropdown(app.UIFigure);
            app.ddKernel.Items = {'Gaussian', 'Mean'};
            app.ddKernel.Value = 'Gaussian';
            app.FilterLabel_3 = uilabel(app.UIFigure);
            app.FilterLabel_3.Text = {'Freq'; 'Method'};
            app.ddFreqMethod = uidropdown(app.UIFigure);
            app.ddFreqMethod.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.ddFreqMethod.Value = 'Ideal';
            app.buttonAddNoise = uibutton(app.UIFigure, 'push');
            app.buttonAddNoise.ButtonPushedFcn = createCallbackFcn(app, @buttonAddNoiseButtonPushed, true);
            app.buttonAddNoise.Text = 'Add Noise';
            app.sigmavalueLabel = uilabel(app.UIFigure);
            app.sigmavalueLabel.Text = {'sigma/'; 'intensity'; 'value'};
            app.sliderSigma = uislider(app.UIFigure);
            app.sliderSigma.Limits = [1 11];
            app.sliderSigma.ValueChangedFcn = createCallbackFcn(app, @sliderSigmaValueChanged, true);
            app.sliderSigma.Value = 1.6;
            app.labelSigma = uilabel(app.UIFigure);
            app.labelSigma.Text = '1.6';
            app.FilterLabel_4 = uilabel(app.UIFigure);
            app.FilterLabel_4.Text = {'Periodic'; 'NoiseType'};
            app.ddNoiseType = uidropdown(app.UIFigure);
            app.ddNoiseType.Items = {'Lena1', 'Lena2', 'Science', 'Building'};
            app.ddNoiseType.Value = 'Lena1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = if4073_tugas2_13519135_13519141

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end